classdef HIE_Tasks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AMYR21Tab                       matlab.ui.container.Tab
        TestLightSensorPanel            matlab.ui.container.Panel
        RunLightSensorTestButton        matlab.ui.control.Button
        EmotionalSelfRatingScalePanel   matlab.ui.container.Panel
        ViewResultsButton               matlab.ui.control.Button
        PresentScaleButton              matlab.ui.control.Button
        StimulationSettingButtonGroup   matlab.ui.container.ButtonGroup
        DuringStimButton                matlab.ui.control.RadioButton
        BeforeStimButton                matlab.ui.control.RadioButton
        TestStimParametersPanel         matlab.ui.container.Panel
        TestStimButton                  matlab.ui.control.Button
        StimDuration                    matlab.ui.control.NumericEditField
        StimDurationsecEditFieldLabel   matlab.ui.control.Label
        StimCurrent                     matlab.ui.control.NumericEditField
        StimulationIntensitymAEditField_2Label  matlab.ui.control.Label
        NumChannels                     matlab.ui.control.DropDown
        StimulationChannelsDropDown_2Label  matlab.ui.control.Label
        StimulatorStatusLamp            matlab.ui.control.Lamp
        StimulatorStatusLamp_2Label     matlab.ui.control.Label
        FormatAMYR21PTLabel             matlab.ui.control.Label
        SubjectID_AMY                   matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        TaskStatusLamp                  matlab.ui.control.Lamp
        TaskStatusLampLabel             matlab.ui.control.Label
        DataPanel                       matlab.ui.container.Panel
        DisplayRecognitionDataButton    matlab.ui.control.Button
        DisplayRatingDataButton         matlab.ui.control.Button
        AddSubjectDatatoDatabaseButton  matlab.ui.control.Button
        RunTaskPanel                    matlab.ui.container.Panel
        RunRecognitionButton            matlab.ui.control.Button
        RunEncodingButton               matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        Table1Label                     matlab.ui.control.Label
        ResultsTable                    matlab.ui.control.Table
        CCEPTab                         matlab.ui.container.Tab
        StimulatorStatusLamp_CCEP       matlab.ui.control.Lamp
        StimulatorStatusLamp_2Label_2   matlab.ui.control.Label
        TaskStatusLamp_CCEP             matlab.ui.control.Lamp
        TaskStatusLamp_2Label           matlab.ui.control.Label
        RunCCEPTaskButton               matlab.ui.control.Button
        StimulationSettingsPanel        matlab.ui.container.Panel
        NumChannels_CCEP                matlab.ui.control.DropDown
        NumberofChannelsDropDownLabel   matlab.ui.control.Label
        ChannelNamesInstructions3       matlab.ui.control.Label
        ChannelNamesInstructions2       matlab.ui.control.Label
        ChannelNamesInstructions1       matlab.ui.control.Label
        ChannelNames                    matlab.ui.control.TextArea
        ChannelNamesTextAreaLabel       matlab.ui.control.Label
        StimPolarity                    matlab.ui.container.ButtonGroup
        BipolarButton                   matlab.ui.control.RadioButton
        MonopolarButton                 matlab.ui.control.RadioButton
        FormatCCEPLabel                 matlab.ui.control.Label
        SubjectID_CCEP                  matlab.ui.control.EditField
        SubjectIDEditField_2Label       matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% AMY TASK %%
        StimChannels % array of channel numbers to be stimulated (ex. [1 2])

        %% CCEP TASK %%
        StimChannels_CCEP % array of channels to be stimulated in CCEP task
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumChannels
        function NumChannelsValueChanged(app, event)
            % set array of channels that will be stimulated based on #
            % selected by user
            numChannels = str2double(app.NumChannels.Value);
            app.StimChannels = 1:numChannels;
        end

        % Button pushed function: RunEncodingButton
        function RunEncodingButtonPushed(app, event)
            % RUN ENCODING TASK %

            % check for valid stim parameters

            if (app.StimCurrent.Value >0) && (app.StimCurrent.Value < 5) ...% amplitude between 0 and 5
                && (str2double(app.NumChannels.Value) >0) ...% number of stim channels > 0
                && (app.StimDuration.Value > 0) % stim duration > 0

                 % change task and stim lights to green
                app.TaskStatusLamp.Color = 'green';
                app.StimulatorStatusLamp.Color = 'green';
                drawnow()

                % run encoding task function
                RunEncodingTask(app.SubjectID_AMY.Value, app.StimChannels, app.StimCurrent.Value);

                % change task and stim lights back to yellow
                app.TaskStatusLamp.Color = 'yellow';
                app.StimulatorStatusLamp.Color = 'yellow';
                drawnow()
            
            else
                % print message indicating stim parameters are not valid
                disp('Error. One or more stimulation parameters entered are not valid')
            end

        end

        % Button pushed function: RunRecognitionButton
        function RunRecognitionButtonPushed(app, event)
            % RUN RECOGNITION TASK %

            % change task light to green
            app.TaskStatusLamp.Color = 'green';
            drawnow()
            % run recognition task function
            RunRecognitionTask(app.SubjectID_AMY.Value)
            % change task light back to yellow
            app.TaskStatusLamp.Color = 'yellow';
            drawnow()
        end

        % Button pushed function: TestStimButton
        function TestStimButtonPushed(app, event)
             %% TEST STIMULATION %%
            
            % check for valid stim parameters

            if (app.StimCurrent.Value >0) && (app.StimCurrent.Value < 5) ...% amplitude between 0 and 5
                && (str2double(app.NumChannels.Value) >0) ...% number of stim channels > 0
                && (app.StimDuration.Value > 0) % stim duration > 0

                % change stim light to green
                app.StimulatorStatusLamp.Color = 'green';
                drawnow()
                
                % run test stimulation function
                TestStim(app.StimCurrent.Value, app.StimChannels, app.StimDuration.Value);
    
                % change task light back to yellow
                app.StimulatorStatusLamp.Color = 'yellow';
                drawnow()
            
            else
                % print message indicating stim parameters are not valid
                disp('Error. One or more stimulation parameters entered are not valid')
            end
           
        end

        % Button pushed function: PresentScaleButton
        function PresentScaleButtonPushed(app, event)
            %% ESR SCALE %%
            
            % change task light to green
            app.TaskStatusLamp.Color = 'green';
            drawnow()

            % check stim setting
            if app.DuringStimButton.Value == true
                ESR_Stim = 1;

                % change stim light to green
                app.StimulatorStatusLamp.Color = 'green';
                drawnow()

            else
                ESR_Stim = 0;
            end

            % present scale, use stim parameters from test stimulation
            ESR_Scale(app.SubjectID_AMY.Value, ESR_Stim, app.StimCurrent.Value, app.StimChannels, app.StimDuration.Value);

            % change task adn stim light back to yellow
            app.TaskStatusLamp.Color = 'yellow';
            app.StimulatorStatusLamp.Color = 'yellow';
            drawnow()
        end

        % Button pushed function: ViewResultsButton
        function ViewResultsButtonPushed(app, event)
            %% Display table comparing ESR scale ratings before and during stimulation

            % get results in table variable
            result_table = ESR_Results(app.SubjectID_AMY.Value);

            % add results data, column and row names to UI table
            app.ResultsTable.Data = result_table;
            app.ResultsTable.ColumnName = {'Before','During','Effect'};
            app.ResultsTable.RowName = {'Anger';'Disgust';'Fear';'Hapiness';'Sadness';'Surprise'};
            % display table
            app.ResultsTable.Visible = 'on';

            % update table header text and display
            app.Table1Label.Text = 'ESR Results';
            app.Table1Label.Visible = 'on';

            % change background color of cells in table
            % red for "yes" green for "no" in stim effect column
            
            % get indices for rows where ratings were different (i.e there
            % was a stim effect)
            redRows = find(result_table.Before ~= result_table.During);
            redCols = ones(length(redRows),1) .*3;
            % get indices for rows where ratings were the same (i.e no stim
            % effect)
            greenRows = find(result_table.Before == result_table.During);
            greenCols = ones(length(greenRows),1) .*3;
            
            redCell = uistyle('BackgroundColor','red');
            addStyle(app.ResultsTable,redCell,'cell',[redRows,redCols]);

            greenCell = uistyle('BackgroundColor','green');
            addStyle(app.ResultsTable,greenCell,'cell',[greenRows,greenCols]);

        end

        % Button pushed function: RunLightSensorTestButton
        function RunLightSensorTestButtonPushed(app, event)
            %% test light sensor 
            TestLightSensor();
        end

        % Button pushed function: AddSubjectDatatoDatabaseButton
        function AddSubjectDatatoDatabaseButtonPushed(app, event)
            %% add subject data to study database
            AddSubjData(app.SubjectID_AMY.Value)
            disp('Subject data added to database')
        end

        % Value changed function: NumChannels_CCEP
        function NumChannels_CCEPValueChanged(app, event)
            % set array of channels to be stimulated based on user input
            % limits: 0-64
            numChannels = str2double(app.NumChannels_CCEP.Value);
            app.StimChannels_CCEP = 1:numChannels;
            disp(numChannels)
            disp(app.StimChannels_CCEP)
            
        end

        % Button pushed function: RunCCEPTaskButton
        function RunCCEPTaskButtonPushed(app, event)
            %% RUN CCEP TASK %%

            % check stim polarity setting for bipolar stim
            if app.BipolarButton.Value == true
                % set stimulated channel to input 1 on Cerestim
                app.StimChannels_CCEP = 1;
            end

            % change task and stim light to green
            app.TaskStatusLamp_CCEP.Color = 'green';
            app.StimulatorStatusLamp_CCEP.Color = 'green';
            drawnow()

            % run task
            disp(app.StimChannels_CCEP)
            RunCCEPTask(app.StimChannels_CCEP)

            % change task adn stim light back to yellow
            app.TaskStatusLamp_CCEP.Color = 'yellow';
            app.StimulatorStatusLamp_CCEP.Color = 'yellow';
            drawnow()

            % save list of channels names to file
            WriteCCEPChannels(app.SubjectID_CCEP.Value, app.ChannelNames.Value);

        end

        % Button pushed function: DisplayRecognitionDataButton
        function DisplayRecognitionDataButtonPushed(app, event)
            %% Display recognition task results in table

            % columns (3): Stim Correct, No Stim Correct, False Positives
            % rows(4): valence and arousal categories

            % reset table display style
            removeStyle(app.ResultsTable)
            
            % calculate results
            result_table = RecognitionTaskResults(app.SubjectID_AMY.Value);
            % update UI data table
            app.ResultsTable.Data = result_table;

            % update column and row names
            app.ResultsTable.ColumnName = {'Stim Correct','No Stim Correct','False Positive'};
            app.ResultsTable.RowName = {'Neg V / Low A','Neg V / High A','Pos V / Low A','Pos V / High A'};

            % display table
            app.ResultsTable.Visible = 'on';

            % update table header text and display
            app.Table1Label.Text = 'Recognition Task Results';
            app.Table1Label.Visible = 'on';


        end

        % Button pushed function: DisplayRatingDataButton
        function DisplayRatingDataButtonPushed(app, event)
            %% Display encoding task results in table

            % columns (4): subject ratings; OASIS ratings
            % rows(8): image categories of stim/valence/arousal

            % reset table display style
            removeStyle(app.ResultsTable)
            
            % calculate results
            result_table = EncodingTaskResults(app.SubjectID_AMY.Value);
            % update UI table
            app.ResultsTable.Data = result_table;

            % update column and row names
            app.ResultsTable.ColumnName = {'V Subj','A Subj','V Stand', 'A Stand'};
            app.ResultsTable.RowName = ["No Stim/Neg V/Low A","Stim/Neg V/Low A",...
        "No Stim/Neg V/High A","Stim/Neg V/High A",...
        "No Stim/Pos V/Low A","Stim/Pos V/Low A",...
        "No Stim/Pos V/High A","Stim/Pos V/High A"];

            % display table
            app.ResultsTable.Visible = 'on';

            % update table header text and display
            app.Table1Label.Text = 'Encoding Task Average Ratings';
            app.Table1Label.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AMYR21Tab = uitab(app.TabGroup);
            app.AMYR21Tab.Title = 'AMYR21';
            app.ResultsPanel = uipanel(app.AMYR21Tab);
            app.ResultsPanel.Title = 'Results';
            app.ResultsTable = uitable(app.ResultsPanel);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.ResultsTable.Visible = 'off';
            app.Table1Label = uilabel(app.ResultsPanel);
            app.Table1Label.Visible = 'off';
            app.Table1Label.Text = 'Table 1 Header';
            app.RunTaskPanel = uipanel(app.AMYR21Tab);
            app.RunTaskPanel.Title = '4. Run Task';
            app.RunEncodingButton = uibutton(app.RunTaskPanel, 'push');
            app.RunEncodingButton.ButtonPushedFcn = createCallbackFcn(app, @RunEncodingButtonPushed, true);
            app.RunEncodingButton.Text = 'Run Encoding';
            app.RunRecognitionButton = uibutton(app.RunTaskPanel, 'push');
            app.RunRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @RunRecognitionButtonPushed, true);
            app.RunRecognitionButton.Text = 'Run Recognition';
            app.DataPanel = uipanel(app.AMYR21Tab);
            app.DataPanel.Title = '5. Data';
            app.AddSubjectDatatoDatabaseButton = uibutton(app.DataPanel, 'push');
            app.AddSubjectDatatoDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectDatatoDatabaseButtonPushed, true);
            app.AddSubjectDatatoDatabaseButton.Text = 'Add Subject Data to Database';
            app.DisplayRatingDataButton = uibutton(app.DataPanel, 'push');
            app.DisplayRatingDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRatingDataButtonPushed, true);
            app.DisplayRatingDataButton.Text = 'Display Rating Data';
            app.DisplayRecognitionDataButton = uibutton(app.DataPanel, 'push');
            app.DisplayRecognitionDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRecognitionDataButtonPushed, true);
            app.DisplayRecognitionDataButton.Text = 'Display Recognition Data';
            app.TaskStatusLampLabel = uilabel(app.AMYR21Tab);
            app.TaskStatusLampLabel.Text = 'Task Status';
            app.TaskStatusLamp = uilamp(app.AMYR21Tab);
            app.SubjectIDEditFieldLabel = uilabel(app.AMYR21Tab);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectID_AMY = uieditfield(app.AMYR21Tab, 'text');
            app.FormatAMYR21PTLabel = uilabel(app.AMYR21Tab);
            app.FormatAMYR21PTLabel.Text = 'Format: "AMYR21PT##"';
            app.StimulatorStatusLamp_2Label = uilabel(app.AMYR21Tab);
            app.StimulatorStatusLamp_2Label.Text = 'Stimulator Status';
            app.StimulatorStatusLamp = uilamp(app.AMYR21Tab);
            app.TestStimParametersPanel = uipanel(app.AMYR21Tab);
            app.TestStimParametersPanel.Title = '1. Test Stim Parameters';
            app.StimulationChannelsDropDown_2Label = uilabel(app.TestStimParametersPanel);
            app.StimulationChannelsDropDown_2Label.Text = 'Stimulation Channels';
            app.NumChannels = uidropdown(app.TestStimParametersPanel);
            app.NumChannels.Items = {'0', '1', '2'};
            app.NumChannels.ItemsData = {'0', '1', '2'};
            app.NumChannels.ValueChangedFcn = createCallbackFcn(app, @NumChannelsValueChanged, true);
            app.NumChannels.Value = '0';
            app.StimulationIntensitymAEditField_2Label = uilabel(app.TestStimParametersPanel);
            app.StimulationIntensitymAEditField_2Label.Text = 'Stimulation Intensity (mA)';
            app.StimCurrent = uieditfield(app.TestStimParametersPanel, 'numeric');
            app.StimDurationsecEditFieldLabel = uilabel(app.TestStimParametersPanel);
            app.StimDurationsecEditFieldLabel.Text = 'Stim Duration (sec)';
            app.StimDuration = uieditfield(app.TestStimParametersPanel, 'numeric');
            app.TestStimButton = uibutton(app.TestStimParametersPanel, 'push');
            app.TestStimButton.ButtonPushedFcn = createCallbackFcn(app, @TestStimButtonPushed, true);
            app.TestStimButton.Text = 'Test Stim';
            app.EmotionalSelfRatingScalePanel = uipanel(app.AMYR21Tab);
            app.EmotionalSelfRatingScalePanel.Title = '2. Emotional Self-Rating Scale';
            app.StimulationSettingButtonGroup = uibuttongroup(app.EmotionalSelfRatingScalePanel);
            app.StimulationSettingButtonGroup.Title = 'Stimulation Setting';
            app.BeforeStimButton = uiradiobutton(app.StimulationSettingButtonGroup);
            app.BeforeStimButton.Text = 'Before Stim';
            app.BeforeStimButton.Value = true;
            app.DuringStimButton = uiradiobutton(app.StimulationSettingButtonGroup);
            app.DuringStimButton.Text = 'During Stim';
            app.PresentScaleButton = uibutton(app.EmotionalSelfRatingScalePanel, 'push');
            app.PresentScaleButton.ButtonPushedFcn = createCallbackFcn(app, @PresentScaleButtonPushed, true);
            app.PresentScaleButton.Text = 'Present Scale';
            app.ViewResultsButton = uibutton(app.EmotionalSelfRatingScalePanel, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewResultsButtonPushed, true);
            app.ViewResultsButton.Text = 'View Results';
            app.TestLightSensorPanel = uipanel(app.AMYR21Tab);
            app.TestLightSensorPanel.Title = '3. Test Light Sensor';
            app.RunLightSensorTestButton = uibutton(app.TestLightSensorPanel, 'push');
            app.RunLightSensorTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunLightSensorTestButtonPushed, true);
            app.RunLightSensorTestButton.Text = 'Run Light Sensor Test';
            app.CCEPTab = uitab(app.TabGroup);
            app.CCEPTab.Title = 'CCEP';
            app.SubjectIDEditField_2Label = uilabel(app.CCEPTab);
            app.SubjectIDEditField_2Label.Text = 'Subject ID';
            app.SubjectID_CCEP = uieditfield(app.CCEPTab, 'text');
            app.FormatCCEPLabel = uilabel(app.CCEPTab);
            app.FormatCCEPLabel.Text = 'Format: "CCEP##"';
            app.StimulationSettingsPanel = uipanel(app.CCEPTab);
            app.StimulationSettingsPanel.Title = 'Stimulation Settings';
            app.StimPolarity = uibuttongroup(app.StimulationSettingsPanel);
            app.StimPolarity.Title = 'Stim Polarity';
            app.MonopolarButton = uiradiobutton(app.StimPolarity);
            app.MonopolarButton.Text = 'Monopolar';
            app.MonopolarButton.Value = true;
            app.BipolarButton = uiradiobutton(app.StimPolarity);
            app.BipolarButton.Text = 'Bipolar';
            app.ChannelNamesTextAreaLabel = uilabel(app.StimulationSettingsPanel);
            app.ChannelNamesTextAreaLabel.Text = 'Channel Names:';
            app.ChannelNames = uitextarea(app.StimulationSettingsPanel);
            app.ChannelNamesInstructions1 = uilabel(app.StimulationSettingsPanel);
            app.ChannelNamesInstructions1.Text = 'List each channel in the order they are plugged into CereStim.';
            app.ChannelNamesInstructions2 = uilabel(app.StimulationSettingsPanel);
            app.ChannelNamesInstructions2.Text = 'Monopolar - one channel per line';
            app.ChannelNamesInstructions3 = uilabel(app.StimulationSettingsPanel);
            app.ChannelNamesInstructions3.Text = ' Bipolar - channel1/channel2';
            app.NumberofChannelsDropDownLabel = uilabel(app.StimulationSettingsPanel);
            app.NumberofChannelsDropDownLabel.Text = 'Number of Channels';
            app.NumChannels_CCEP = uidropdown(app.StimulationSettingsPanel);
            app.NumChannels_CCEP.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumChannels_CCEP.ValueChangedFcn = createCallbackFcn(app, @NumChannels_CCEPValueChanged, true);
            app.NumChannels_CCEP.Value = '0';
            app.RunCCEPTaskButton = uibutton(app.CCEPTab, 'push');
            app.RunCCEPTaskButton.ButtonPushedFcn = createCallbackFcn(app, @RunCCEPTaskButtonPushed, true);
            app.RunCCEPTaskButton.Text = 'Run CCEP Task';
            app.TaskStatusLamp_2Label = uilabel(app.CCEPTab);
            app.TaskStatusLamp_2Label.Text = 'Task Status';
            app.TaskStatusLamp_CCEP = uilamp(app.CCEPTab);
            app.StimulatorStatusLamp_2Label_2 = uilabel(app.CCEPTab);
            app.StimulatorStatusLamp_2Label_2.Text = 'Stimulator Status';
            app.StimulatorStatusLamp_CCEP = uilamp(app.CCEPTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HIE_Tasks

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end