classdef EMO_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TasksTab                        matlab.ui.container.Tab
        SubjectIDEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        TaskStatusLamp                  matlab.ui.control.Lamp
        TaskStatusLampLabel             matlab.ui.control.Label
        RecognitionPanel                matlab.ui.container.Panel
        RunButton_2                     matlab.ui.control.Button
        EncodingPanel                   matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        StimulationIntensitymAEditField  matlab.ui.control.NumericEditField
        StimulationIntensitymAEditFieldLabel  matlab.ui.control.Label
        StimulationChannelsDropDown     matlab.ui.control.DropDown
        StimulationChannelsDropDownLabel  matlab.ui.control.Label
        DataTab                         matlab.ui.container.Tab
        TestingStimTab                  matlab.ui.container.Tab
        SubjectIDEditField_2            matlab.ui.control.EditField
        SubjectIDLabel                  matlab.ui.control.Label
        EmotionalSelfRatingScalePanel   matlab.ui.container.Panel
        PresentScaleButton              matlab.ui.control.Button
        StimulationSettingButtonGroup   matlab.ui.container.ButtonGroup
        DuringStimButton                matlab.ui.control.RadioButton
        BeforeStimButton                matlab.ui.control.RadioButton
        TestStimulationParametersPanel  matlab.ui.container.Panel
        TestStimButton                  matlab.ui.control.Button
        StimDurationsecEditField        matlab.ui.control.NumericEditField
        StimDurationsecEditFieldLabel   matlab.ui.control.Label
        StimulationIntensitymAEditField_2  matlab.ui.control.NumericEditField
        StimulationIntensitymAEditField_2Label  matlab.ui.control.Label
        StimulationChannelsDropDown_2   matlab.ui.control.DropDown
        StimulationChannelsDropDown_2Label  matlab.ui.control.Label
        StimulatorStatusLamp            matlab.ui.control.Lamp
        StimulatorStatusLampLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        SubjID % user input subject ID
        StimChannels % array of channel numbers to be stimulated (ex. [1 2])
        StimAmplitude % stimulation amplitude in mA
        TestStimChannels % array of channels used for test stimulation
        TestStimAmplitude % test stim amplitude
        TestStimTime % duration of test stim in seconds
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SubjectIDEditFieldValueChanged(app, event)
            % set subject ID to user input
            app.SubjID = app.SubjectIDEditField.Value;

        end

        % Value changed function: StimulationChannelsDropDown
        function StimulationChannelsDropDownValueChanged(app, event)
            % set array of channels that will be stimulated based on #
            % selected by user
            numChannels = str2double(app.StimulationChannelsDropDown.Value);
            app.StimChannels = 1:numChannels;
        end

        % Value changed function: StimulationIntensitymAEditField
        function StimulationIntensitymAEditFieldValueChanged(app, event)
            % set stimulation amplitude to user input
            app.StimAmplitude = app.StimulationIntensitymAEditField.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % RUN ENCODING TASK %

            disp(app.SubjID)

            % change task light to green
            app.TaskStatusLamp.Color = 'green';
            drawnow()
            % run encoding task function
            RunEncodingTask(app.SubjID,app.StimChannels,app.StimAmplitude);
            % change task light back to yellow
            app.TaskStatusLamp.Color = 'yellow';
            drawnow()
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            % RUN RECOGNITION TASK %
            
            disp(app.SubjID)

            % change task light to green
            app.TaskStatusLamp.Color = 'green';
            drawnow()
            % run recognition task function
            RunRecognitionTask(app.SubjID)
            % change task light back to yellow
            app.TaskStatusLamp.Color = 'yellow';
            drawnow()
        end

        % Value changed function: StimulationChannelsDropDown_2
        function StimulationChannelsDropDown_2ValueChanged(app, event)
            %% TEST STIMULATION %%
            % set array of channels that will be stimulated
            numChannels = str2double(app.StimulationChannelsDropDown_2.Value);
            app.TestStimChannels = 1:numChannels;
            
        end

        % Value changed function: StimulationIntensitymAEditField_2
        function StimulationIntensitymAEditField_2ValueChanged(app, event)
            %% TEST STIMULATION %%
            % set test stimulation amplitude to user input
            app.TestStimAmplitude = app.StimulationIntensitymAEditField_2.Value;
            
        end

        % Value changed function: StimDurationsecEditField
        function StimDurationsecEditFieldValueChanged(app, event)
            %% TEST STIMULATION %%
            % set duration of stim
            app.TestStimTime = app.StimDurationsecEditField.Value;
            
        end

        % Button pushed function: TestStimButton
        function TestStimButtonPushed(app, event)
             %% TEST STIMULATION %%
            
            % change task light to green
            app.StimulatorStatusLamp.Color = 'green';
            drawnow()
            
            % run test stimulation function
            TestStim(app.TestStimAmplitude, app.TestStimChannels, app.TestStimTime);

            % change task light back to yellow
            app.StimulatorStatusLamp.Color = 'yellow';
            drawnow()
           
        end

        % Button pushed function: PresentScaleButton
        function PresentScaleButtonPushed(app, event)
            %% ESR SCALE %%
            
            disp(app.SubjID)

            % check stim setting
            if app.DuringStimButton.Value == true
                ESR_Stim = 1;

                % change task light to green
                app.StimulatorStatusLamp.Color = 'green';
                drawnow()

            else
                ESR_Stim = 0;
            end

            % present scale, use stim parameters from test stimulation
            ESR_Scale(app.SubjID, ESR_Stim, app.TestStimAmplitude, app.TestStimChannels, app.TestStimTime);

            % change task light back to yellow
            app.StimulatorStatusLamp.Color = 'yellow';
            drawnow()
        end

        % Callback function
        function StimulationSettingButtonGroupSelectionChanged(app, event)

        end

        % Callback function
        function StimulationSettingButtonGroupButtonDown(app, event)
            
        end

        % Value changed function: SubjectIDEditField_2
        function SubjectIDEditField_2ValueChanged(app, event)
            % update subject ID variable
            app.SubjID = app.SubjectIDEditField_2.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TasksTab = uitab(app.TabGroup);
            app.TasksTab.Title = 'Tasks';
            app.EncodingPanel = uipanel(app.TasksTab);
            app.EncodingPanel.Title = 'Encoding';
            app.StimulationChannelsDropDownLabel = uilabel(app.EncodingPanel);
            app.StimulationChannelsDropDownLabel.Text = 'Stimulation Channels';
            app.StimulationChannelsDropDown = uidropdown(app.EncodingPanel);
            app.StimulationChannelsDropDown.Items = {'0', '1', '2'};
            app.StimulationChannelsDropDown.ItemsData = {'0', '1', '2'};
            app.StimulationChannelsDropDown.ValueChangedFcn = createCallbackFcn(app, @StimulationChannelsDropDownValueChanged, true);
            app.StimulationChannelsDropDown.Value = '0';
            app.StimulationIntensitymAEditFieldLabel = uilabel(app.EncodingPanel);
            app.StimulationIntensitymAEditFieldLabel.Text = 'Stimulation Intensity (mA)';
            app.StimulationIntensitymAEditField = uieditfield(app.EncodingPanel, 'numeric');
            app.StimulationIntensitymAEditField.ValueChangedFcn = createCallbackFcn(app, @StimulationIntensitymAEditFieldValueChanged, true);
            app.RunButton = uibutton(app.EncodingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RecognitionPanel = uipanel(app.TasksTab);
            app.RecognitionPanel.Title = 'Recognition';
            app.RunButton_2 = uibutton(app.RecognitionPanel, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.TaskStatusLampLabel = uilabel(app.TasksTab);
            app.TaskStatusLampLabel.Text = 'Task Status';
            app.TaskStatusLamp = uilamp(app.TasksTab);
            app.SubjectIDEditFieldLabel = uilabel(app.TasksTab);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.TasksTab, 'text');
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.TestingStimTab = uitab(app.TabGroup);
            app.TestingStimTab.Title = 'Testing Stim';
            app.StimulatorStatusLampLabel = uilabel(app.TestingStimTab);
            app.StimulatorStatusLampLabel.Text = 'Stimulator Status';
            app.StimulatorStatusLamp = uilamp(app.TestingStimTab);
            app.TestStimulationParametersPanel = uipanel(app.TestingStimTab);
            app.TestStimulationParametersPanel.Title = 'Test Stimulation Parameters';
            app.StimulationChannelsDropDown_2Label = uilabel(app.TestStimulationParametersPanel);
            app.StimulationChannelsDropDown_2Label.Text = 'Stimulation Channels';
            app.StimulationChannelsDropDown_2 = uidropdown(app.TestStimulationParametersPanel);
            app.StimulationChannelsDropDown_2.Items = {'0', '1', '2'};
            app.StimulationChannelsDropDown_2.ItemsData = {'0', '1', '2'};
            app.StimulationChannelsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StimulationChannelsDropDown_2ValueChanged, true);
            app.StimulationChannelsDropDown_2.Value = '0';
            app.StimulationIntensitymAEditField_2Label = uilabel(app.TestStimulationParametersPanel);
            app.StimulationIntensitymAEditField_2Label.Text = 'Stimulation Intensity (mA)';
            app.StimulationIntensitymAEditField_2 = uieditfield(app.TestStimulationParametersPanel, 'numeric');
            app.StimulationIntensitymAEditField_2.ValueChangedFcn = createCallbackFcn(app, @StimulationIntensitymAEditField_2ValueChanged, true);
            app.StimDurationsecEditFieldLabel = uilabel(app.TestStimulationParametersPanel);
            app.StimDurationsecEditFieldLabel.Text = 'Stim Duration (sec)';
            app.StimDurationsecEditField = uieditfield(app.TestStimulationParametersPanel, 'numeric');
            app.StimDurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @StimDurationsecEditFieldValueChanged, true);
            app.TestStimButton = uibutton(app.TestStimulationParametersPanel, 'push');
            app.TestStimButton.ButtonPushedFcn = createCallbackFcn(app, @TestStimButtonPushed, true);
            app.TestStimButton.Text = 'Test Stim';
            app.EmotionalSelfRatingScalePanel = uipanel(app.TestingStimTab);
            app.EmotionalSelfRatingScalePanel.Title = 'Emotional Self-Rating Scale';
            app.StimulationSettingButtonGroup = uibuttongroup(app.EmotionalSelfRatingScalePanel);
            app.StimulationSettingButtonGroup.Title = 'Stimulation Setting';
            app.BeforeStimButton = uiradiobutton(app.StimulationSettingButtonGroup);
            app.BeforeStimButton.Text = 'Before Stim';
            app.BeforeStimButton.Value = true;
            app.DuringStimButton = uiradiobutton(app.StimulationSettingButtonGroup);
            app.DuringStimButton.Text = 'During Stim';
            app.PresentScaleButton = uibutton(app.EmotionalSelfRatingScalePanel, 'push');
            app.PresentScaleButton.ButtonPushedFcn = createCallbackFcn(app, @PresentScaleButtonPushed, true);
            app.PresentScaleButton.Text = 'Present Scale';
            app.SubjectIDLabel = uilabel(app.TestingStimTab);
            app.SubjectIDLabel.Text = 'Subject ID';
            app.SubjectIDEditField_2 = uieditfield(app.TestingStimTab, 'text');
            app.SubjectIDEditField_2.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMO_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end